classdef OneDDemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Image         matlab.ui.control.Image
        aSlider       matlab.ui.control.Slider
        aSliderLabel  matlab.ui.control.Label
        UIAxes3       matlab.ui.control.UIAxes
        UIAxes2       matlab.ui.control.UIAxes
        UIAxes        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = f(app, xs)
            results = xs.*(xs-1).*(xs+1)./(xs.^2+1);
        end
        
        function results = pot(app, xs)
            results = log(xs.^2 + 1) - xs.^2/2;            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            xs = -3:0.03:3;
            plot(app.UIAxes, xs, zeros(length(xs)), '--k')
            hold(app.UIAxes, 'on')
            fs = f(app,xs);
            plot(app.UIAxes, xs, fs, '-r');
            hold(app.UIAxes, 'off')
            
            xs2 = -3:0.2:3;
            fs2 = f(app,xs2);
            fs2 = fs2/2 ./ max(fs2);
            [fs2, v] = meshgrid(fs2, [0]);
            quiver(app.UIAxes2, xs2, [0], fs2, v, 'r')
            boundtheoretic = 1/3;
            boundreal = 1;
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2, [boundreal boundreal], [0.15 -0.15], 'm-')
            plot(app.UIAxes2, [-boundreal -boundreal], [0.15 -0.15], 'm-')
            plot(app.UIAxes2, [boundtheoretic boundtheoretic], [0.13 -0.13], 'g-')
            plot(app.UIAxes2, [-boundtheoretic -boundtheoretic], [0.13 -0.13], 'g-')
            hold(app.UIAxes2, 'off')
            
            pots = pot(app,xs);
            plot(app.UIAxes3, xs, pots, 'b-')
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3, [boundreal boundreal], [0 max(pots)*1.3], 'm--')
            plot(app.UIAxes3, [-boundreal -boundreal], [0 max(pots)*1.3], 'm--')
            plot(app.UIAxes3, [boundtheoretic boundtheoretic], [0 max(pots)*0.7], 'g--')
            plot(app.UIAxes3, [-boundtheoretic -boundtheoretic], [0 max(pots)*0.7], 'g--')
            hold(app.UIAxes3, 'off')
        end

        % Value changed function: aSlider
        function aSliderValueChanged(app, event)
            value = app.aSlider.Value;
            xs = -3:0.03:3;
            plot(app.UIAxes, xs, zeros(length(xs)), '-k')
            hold(app.UIAxes, 'on')
            fs = f(app,xs) - value*xs;
            plot(app.UIAxes, xs, fs, '-r');
            hold(app.UIAxes, 'off')
            app.UIAxes.XLim = [-3 3];
            app.UIAxes.YLim = [-2 2];
            
            xs2 = -3:0.2:3;
            fs2 = f(app,xs2) - value*xs2;
            fs2 = fs2/2 ./ max(fs2);
            [fs2, v] = meshgrid(fs2, [0]);
            quiver(app.UIAxes2, xs2, [0], fs2, v, 'r', 'Marker', '.')
            boundtheoretic = (1+value)/3;
            boundreal = sqrt((1+value)/(1-value+1e-16));
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2, [boundreal boundreal], [0.15 -0.15], 'm-')
            plot(app.UIAxes2, [-boundreal -boundreal], [0.15 -0.15], 'm-')
            plot(app.UIAxes2, [boundtheoretic boundtheoretic], [0.13 -0.13], 'g-')
            plot(app.UIAxes2, [-boundtheoretic -boundtheoretic], [0.13 -0.13], 'g-')
            hold(app.UIAxes2, 'off')
            
            pots = pot(app,xs) + value/2*xs.^2;
            plot(app.UIAxes3, xs, pots, 'b-')
            hold(app.UIAxes3, "on")
            plot(app.UIAxes3, [boundreal boundreal], [0 max(pots)*1.3], 'm--')
            plot(app.UIAxes3, [-boundreal -boundreal], [0 max(pots)*1.3], 'm--')
            plot(app.UIAxes3, [boundtheoretic boundtheoretic], [0 max(pots)*0.7], 'g--')
            plot(app.UIAxes3, [-boundtheoretic -boundtheoretic], [0 max(pots)*0.7], 'g--')
            hold(app.UIAxes3, "off")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Rate of change of x(t)')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'f(x,u)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-3 3];
            app.UIAxes.YLim = [-2 2];
            app.UIAxes.XTick = [-3 -2 -1 0 1 2 3];
            app.UIAxes.XTickLabel = {'-3'; '-2'; '-1'; '0'; '1'; '2'; '3'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Phase portrait')
            xlabel(app.UIAxes2, 'x')
            ylabel(app.UIAxes2, 'd/dt x')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [-3 3];
            app.UIAxes2.YLim = [-0.5 0.5];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Potential')
            xlabel(app.UIAxes3, 'x')
            ylabel(app.UIAxes3, 'F(x)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XLim = [-3 3];
            app.UIAxes3.YLim = [-0.2 3];
            app.aSliderLabel = uilabel(app.UIFigure);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.UIFigure);
            app.aSlider.Limits = [0 1];
            app.aSlider.ValueChangedFcn = createCallbackFcn(app, @aSliderValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '1dformula.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OneDDemo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end