classdef TwoDDemov2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Image             matlab.ui.control.Image
        CalculateButton   matlab.ui.control.Button
        q2EditField       matlab.ui.control.NumericEditField
        q2EditFieldLabel  matlab.ui.control.Label
        q1EditField       matlab.ui.control.NumericEditField
        q1EditFieldLabel  matlab.ui.control.Label
        u2EditField       matlab.ui.control.NumericEditField
        u2EditFieldLabel  matlab.ui.control.Label
        u1EditField       matlab.ui.control.NumericEditField
        u1EditFieldLabel  matlab.ui.control.Label
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [res1, res2] = f(app, xs, ys)
            rs2 = xs.^2 + ys.^2;
            res1 = xs.*(rs2 - 1)./(rs2 + 1) - ys; 
            res2 = ys.*(rs2 - 1)./(rs2 + 1) + xs;
        end
        
        function [res1, res2] = fv(app, xs, ys, u1, u2, q1, q2)
            rs2 = xs.^2 + ys.^2;
            res1 = xs.*(rs2 - 1)./(rs2 + 1) - (1+q2*u2)*ys + u1*xs;
            res2 = ys.*(rs2 - 1)./(rs2 + 1) + (1+q1*u1)*xs + u2*ys;
        end
        
        function [xs, ys] = roa(app, u1, u2, q1, q2)
            N = 4000;
            x0 = 0.2;
            y0 = 0.2;
            xs = [x0, zeros(1,N-1)];
            ys = [y0, zeros(1,N-1)];
            dt = 0.01;
            for i = 1:(N-1)
                [dx, dy] = fv(app, xs(i), ys(i), u1, u2, q1, q2);
                xs(i+1) = xs(i) - dt*dx;
                ys(i+1) = ys(i) - dt*dy;
            end
            xs = xs(N/2:end);
            ys = ys(N/2:end);
        end
        
        function res = pot(app, xs, ys, u1, u2, q1, q2)
            res = zeros(length(xs),length(ys));
            dx = xs(2)-xs(1);
            dy = ys(2)-ys(1);
            xmid = ceil(length(xs)/2);
            ymid = ceil(length(ys)/2);
            for i = 1:length(xs)-1
                [gradx, ~] = fv(app, xs(i), ys(ymid), u1, u2, q1, q2);
                res(i+1,ymid) = res(i,ymid) + dx*gradx;
            end
%             for j = 1:length(ys)-1
%                 for i = 1:length(xs)
%                     [~, grady] = fv(app, xs(i), ys(j), u1, u2, q1, q2);
%                     res(i,j+1) = res(i,j) + dy*grady;
%                 end
%             end
            
            for j = 0:length(ys)-ymid-1
                for i = 1:length(xs)
                    [~, grady] = fv(app, xs(i), ys(ymid+j), u1, u2, q1, q2);
                    res(i,ymid+j+1) = res(i,ymid+j) + dy*grady;
                    [~, grady] = fv(app, xs(i), ys(ymid-j), u1, u2, q1, q2);
                    res(i,ymid-j-1) = res(i,ymid-j) - dy*grady;
                end
            end
            res = res - res(xmid,ymid);
            
%             syms x y
%             P = potential([x*(x^2+y^2 - 1)/(x^2+y^2 + 1) - (1+q2*u2)*y + u1*x, y*(x^2+y^2 - 1)/(x^2+y^2 + 1) + (1+q1*u1)*x + u2*y], [x y])
%             res = zeros(length(xs),length(ys));
%             for i = 1:length(xs)
%                 for j = 1:length(ys)
%                     res(i,j) = P(xs(i),ys(j));
%                 end
%             end
        end
        
        function res = pot2(app, xs, ys, u1, u2, q1, q2)
            d = -(2*u1*u2*q1*q2 + 4*u1*u2 + 4 - u1^2*q1^2 - u2^2*q2^2 -4*u1 -4*u2);
            P = 1/d*[[2*(-1+u2), -u1*q1+u2*q2];[-u1*q1+u2*q2, 2*(-1+u1)]];
            res = zeros(length(xs),length(ys));
            for i = 1:length(xs)
                for j = 1:length(ys)
                    z = [xs(i); ys(j)];
                    res(i,j) = z'*P*z;
                end
            end
        end
        
        function [xs, ys1, ys2] = roaeig(app, u1, u2, q1, q2)
            %d = -(2*u1*u2*q1*q2 + 4*u1*u2 + 4 - u1^2*q1^2 - u2^2*q2^2 -4*u1 -4*u2);
            %P = 1/d*[[2*(-1+u2), -u1*q1+u2*q2];[-u1*q1+u2*q2, 2*(-1+u1)]];
            a1 = -1+u1;
            a2 = -(1+q2*u2);
            a3 = 1+q1*u1;
            a4=-1+u2;
            A=[a1,a2;a3,a4];
            AA=[[2*a1, 2*a3, 0];[a2,a1+a4,a3];[0,2*a2,2*a4]];
            b = [-1;0;-1];
            p = AA\b;
            p1 = p(1);
            p2 = p(2);
            p4 = p(3);
            P = [[p1, p2];[p2,p4]];
            II = transpose(A)*P+P*A;

            [~,D] = eig(P);
            l1 = max(diag(D)); ln = min(diag(D));
            M = 50/27*sqrt(5);
            xs = -3:0.01:3;
            size(xs)
            a = p1; b = p2; c = p4;
            ys1 = real(-b*xs + (b^2*xs.^2 - c*(a*xs.^2 - ln/(4*M^2*l1^2)*c)).^(1/2))/c;
            ys2 = real(-b*xs - (b^2*xs.^2 - c*(a*xs.^2 - ln/(4*M^2*l1^2)*c)).^(1/2))/c; 
            eqy = (ys1 ~= ys2);
            eqy = or(eqy, or(circshift(eqy,1),circshift(eqy,-1)));
            xs = xs(eqy); ys1 = ys1(eqy); ys2 = ys2(eqy);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [xs, ys] = meshgrid(-3:0.3:3,-3:0.3:3);
            [us, vs] = f(app, xs, ys);
            quiver(app.UIAxes, xs, ys, us, vs)
            hold(app.UIAxes,'on')
            thetas = [0:pi/20:2*pi 0];
            plot(app.UIAxes, cos(thetas), sin(thetas), 'r-')
            [roax, roay1, roay2] = roaeig(app, 0, 0, 0, 0);
            plot(app.UIAxes, roax, roay1, 'm-')
            plot(app.UIAxes, roax, roay2, 'm-')
            hold(app.UIAxes,'off')
            
            pots = pot2(app, -3:0.3:3, -3:0.3:3, 0, 0, 0, 0);
            surf(app.UIAxes2, xs, ys, pots')
            
           
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            u1 = app.u1EditField.Value;
            u2 = app.u2EditField.Value;
            q1 = app.q1EditField.Value;
            q2 = app.q2EditField.Value;
            [xs, ys] = meshgrid(-3:0.3:3,-3:0.3:3);
            [us, vs] = fv(app, xs, ys, u1, u2, q1, q2);
            quiver(app.UIAxes, xs, ys, us, vs)

            hold(app.UIAxes,'on')
            [x2s, y2s] = roa(app, u1, u2, q1, q2);
            plot(app.UIAxes, x2s, y2s, 'r-')
            [roax, roay1, roay2] = roaeig(app, u1, u2, q1, q2);
            plot(app.UIAxes, roax, roay1, 'm-')
            plot(app.UIAxes, roax, roay2, 'm-')
            hold(app.UIAxes,'off')
            
            [xs2, ys2] = meshgrid(-3:0.3:3, -3:0.3:3);
            pots = pot2(app, -3:0.3:3, -3:0.3:3, u1, u2, q1, q2);
            surf(app.UIAxes2, xs2, ys2, pots)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Phase portrait')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-3 3];
            app.UIAxes.YLim = [-3 3];
            app.UIAxes.XTick = [-3 -2 -1 0 1 2 3];
            app.UIAxes.XTickLabel = {'-3'; '-2'; '-1'; '0'; '1'; '2'; '3'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'V(x)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [-3 3];
            app.UIAxes2.YLim = [-3 3];
            app.UIAxes2.ZLim = [0 9];
            app.u1EditFieldLabel = uilabel(app.UIFigure);
            app.u1EditFieldLabel.Text = 'u1';
            app.u1EditField = uieditfield(app.UIFigure, 'numeric');
            app.u2EditFieldLabel = uilabel(app.UIFigure);
            app.u2EditFieldLabel.Text = 'u2';
            app.u2EditField = uieditfield(app.UIFigure, 'numeric');
            app.q1EditFieldLabel = uilabel(app.UIFigure);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.q2EditFieldLabel = uilabel(app.UIFigure);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '2dformula.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TwoDDemov2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end